classdef ODIN_config < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        helpMenu                        matlab.ui.container.Menu
        SPIMenu                         matlab.ui.container.Menu
        NeuronMenu                      matlab.ui.container.Menu
        MEMMenu                         matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        forSPIEditFieldLabel            matlab.ui.control.Label
        forSPIEditField                 matlab.ui.control.EditField
        ODINconfiguratorLabel           matlab.ui.control.Label
        forcoeLabel                     matlab.ui.control.Label
        forcoeEditField                 matlab.ui.control.EditField
        Tab                             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SPI_GATE_ACTIVITYEditFieldLabel  matlab.ui.control.Label
        SPI_GATE_ACTIVITYEditField      matlab.ui.control.NumericEditField
        SPI_OPEN_LOOPEditFieldLabel     matlab.ui.control.Label
        SPI_OPEN_LOOPEditField          matlab.ui.control.NumericEditField
        SPI_AER_SRC_CTRL_nNEUREditFieldLabel  matlab.ui.control.Label
        SPI_AER_SRC_CTRL_nNEUREditField  matlab.ui.control.NumericEditField
        SPI_OUT_AER_MONITOR_ENEditFieldLabel  matlab.ui.control.Label
        SPI_OUT_AER_MONITOR_ENEditField  matlab.ui.control.NumericEditField
        SPI_MONITOR_NEUR_ADDRforNEditFieldLabel  matlab.ui.control.Label
        SPI_MONITOR_NEUR_ADDRforNEditField  matlab.ui.control.NumericEditField
        OutputButton_2                  matlab.ui.control.Button
        SPI_MONITOR_SYN_ADDRforNEditFieldLabel  matlab.ui.control.Label
        SPI_MONITOR_SYN_ADDRforNEditField  matlab.ui.control.NumericEditField
        SPI_UPDATE_UNMAPPED_SYNEditFieldLabel  matlab.ui.control.Label
        SPI_UPDATE_UNMAPPED_SYNEditField  matlab.ui.control.NumericEditField
        SPI_PROPAGATE_UNMAPPED_SYNEditFieldLabel  matlab.ui.control.Label
        SPI_PROPAGATE_UNMAPPED_SYNEditField  matlab.ui.control.NumericEditField
        SPI_SDSP_ON_SYN_STIMEditFieldLabel  matlab.ui.control.Label
        SPI_SDSP_ON_SYN_STIMEditField   matlab.ui.control.NumericEditField
        NM0255Label                     matlab.ui.control.Label
        SPI_SYN_SIGNforNMifnotinputnoneEditFieldLabel  matlab.ui.control.Label
        SPI_SYN_SIGNforNMifnotinputnoneEditField  matlab.ui.control.EditField
        Tab_2                           matlab.ui.container.Tab
        READLabel                       matlab.ui.control.Label
        OutputButton_3                  matlab.ui.control.Button
        WRITELabel                      matlab.ui.control.Label
        word_addrEditFieldLabel         matlab.ui.control.Label
        word_addrEditField              matlab.ui.control.NumericEditField
        byte_addrEditFieldLabel         matlab.ui.control.Label
        byte_addrEditField              matlab.ui.control.NumericEditField
        NeuronLabel                     matlab.ui.control.Label
        SynapseLabel                    matlab.ui.control.Label
        OutputButton_4                  matlab.ui.control.Button
        word_addrEditFieldLabel_2       matlab.ui.control.Label
        word_addrEditField_2            matlab.ui.control.NumericEditField
        byte_addrEditFieldLabel_2       matlab.ui.control.Label
        byte_addrEditField_2            matlab.ui.control.NumericEditField
        OutputButton_5                  matlab.ui.control.Button
        NeuronLabel_2                   matlab.ui.control.Label
        SynapseLabel_2                  matlab.ui.control.Label
        OutputButton_6                  matlab.ui.control.Button
        word_addrEditFieldLabel_3       matlab.ui.control.Label
        word_addrEditField_3            matlab.ui.control.NumericEditField
        byte_addrEditFieldLabel_3       matlab.ui.control.Label
        byte_addrEditField_3            matlab.ui.control.NumericEditField
        word_addrEditField_4Label       matlab.ui.control.Label
        word_addrEditField_4            matlab.ui.control.NumericEditField
        byte_addrEditField_4Label       matlab.ui.control.Label
        byte_addrEditField_4            matlab.ui.control.NumericEditField
        dataEditFieldLabel              matlab.ui.control.Label
        dataEditField                   matlab.ui.control.EditField
        MaskEditFieldLabel              matlab.ui.control.Label
        MaskEditField                   matlab.ui.control.EditField
        dataEditField_2Label            matlab.ui.control.Label
        dataEditField_2                 matlab.ui.control.EditField
        MaskEditField_2Label            matlab.ui.control.Label
        MaskEditField_2                 matlab.ui.control.EditField
        masked0nonmaskedLabel           matlab.ui.control.Label
        Tab_3                           matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LIF_sel1forLIFneuronLabel       matlab.ui.control.Label
        LIF_sel1forLIFneuronEditField   matlab.ui.control.NumericEditField
        leak_str0127theamountofleakageEditFieldLabel  matlab.ui.control.Label
        leak_str0127theamountofleakageEditField  matlab.ui.control.NumericEditField
        leak_en1forEnableEditFieldLabel  matlab.ui.control.Label
        leak_en1forEnableEditField      matlab.ui.control.NumericEditField
        thr0255thresholdEditFieldLabel  matlab.ui.control.Label
        thr0255thresholdEditField       matlab.ui.control.NumericEditField
        ca_en1forEnableEditFieldLabel   matlab.ui.control.Label
        ca_en1forEnableEditField        matlab.ui.control.NumericEditField
        thetamem0255theSDSPthresholdEditFieldLabel  matlab.ui.control.Label
        thetamem0255theSDSPthresholdEditField  matlab.ui.control.NumericEditField
        ca_theta107EditFieldLabel       matlab.ui.control.Label
        ca_theta107EditField            matlab.ui.control.NumericEditField
        ca_theta207EditFieldLabel       matlab.ui.control.Label
        ca_theta207EditField            matlab.ui.control.NumericEditField
        ca_theta307EditFieldLabel       matlab.ui.control.Label
        ca_theta307EditField            matlab.ui.control.NumericEditField
        ca_leak031CalciumvariableleakagetimeconstantEditFieldLabel  matlab.ui.control.Label
        ca_leak031CalciumvariableleakagetimeconstantEditField  matlab.ui.control.NumericEditField
        caleak_cnt031CalciumvariableleakagecounterEditFieldLabel  matlab.ui.control.Label
        caleak_cnt031CalciumvariableleakagecounterEditField  matlab.ui.control.NumericEditField
        neur_disable1forDisablestheneuronEditFieldLabel  matlab.ui.control.Label
        neur_disable1forDisablestheneuronEditField  matlab.ui.control.NumericEditField
        core0255membranepotentialEditFieldLabel  matlab.ui.control.Label
        core0255membranepotentialEditField  matlab.ui.control.NumericEditField
        calcium07CalciumvariableEditFieldLabel  matlab.ui.control.Label
        calcium07CalciumvariableEditField  matlab.ui.control.NumericEditField
        OutputButton                    matlab.ui.control.Button
        fEditField                      matlab.ui.control.NumericEditField
        specifytheneuroninrange0255EditFieldLabel  matlab.ui.control.Label
        specifytheneuroninrange0255EditField  matlab.ui.control.NumericEditField
        Tab_4                           matlab.ui.container.Tab
        setthemappingtablebit01EditFieldLabel  matlab.ui.control.Label
        setthemappingtablebit01EditField  matlab.ui.control.NumericEditField
        setthesynapticweight07EditFieldLabel  matlab.ui.control.Label
        setthesynapticweight07EditField  matlab.ui.control.NumericEditField
        SetButton                       matlab.ui.control.Button
        OutputButton_7                  matlab.ui.control.Button
        Typethepresynapticneuronnumber0255EditField_2Label  matlab.ui.control.Label
        Typethepresynapticneuronnumber0255EditField_2  matlab.ui.control.EditField
        Typethepostsynapticneuronnumber0255EditField_2Label  matlab.ui.control.Label
        Typethepostsynapticneuronnumber0255EditField_2  matlab.ui.control.EditField
        defaultmappingtablebit1enablesynapticweight2Label  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Description
        synapse
    end
    
    methods (Access = public)
        
        function writetxt(~, data,path)
            dataout = data;
            pathout=path;
            dimen=size(dataout);
            x=dimen(1);
            y=dimen(2);
            
            %   path=char(address);
            f=fullfile(pathout,'data.txt');
            
            fid = fopen (f,'a');
            for i=1:x
                for j=1:y
                    fprintf(fid,'%d',dataout(i,j));
                end
                fprintf(fid,'\n');
            end
            fclose(fid);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            synapse_data=load("synapse.mat");
            app.synapse=synapse_data.z;
            
            %name
            app.UIFigure.Name='ODIN configurator';
        end

        % Button pushed function: OutputButton_2
        function OutputButton_2Pushed(app, event)
            struct=load("Config_data.mat");
            config_data=struct.z;
            config_data(1,40)=app.SPI_GATE_ACTIVITYEditField.Value;
            config_data(2,40)=app.SPI_OPEN_LOOPEditField.Value;
            
%             %for N
%             index=app.SPI_SYN_SIGNforNEditField.Value;
%             chushu=floor(index/16);
%             yushu=rem(index,16);
%             hang=chushu+3;
%             lie=40-yushu;
%             config_data(hang,lie)=1;
%             %for N~M
%             index1=app.SPI_SYN_SIGNforNMEditField.Value;
%             index2=app.SPI_SYN_SIGNfor150EditField_3.Value;
%             chushu1=floor(index1/16);
%             yushu1=rem(index1,16);
%             hang1=chushu1+3;
%             lie1=40-yushu1;
%             chushu2=floor(index2/16);
%             yushu2=rem(index2,16);
%             hang2=chushu2+3;
%             lie2=40-yushu2;
%             if hang1==hang2
%                 config_data(hang1,lie2:lie1)=1;
%             end
%             
%             if hang2-hang1==1
%                 config_data(hang1,21:lie1)=1;
%                 config_data(hang2,lie2:40)=1;
%             end
%             
%             if hang2-hang1>1
%                 config_data(hang1,21:lie1)=1;
%                 config_data(hang2,lie2:40)=1;
%                 for i=hang1+1:hang2-1
%                     config_data(i,21:40)=1;
%                 end
%             end
            
            indexsyn=app.SPI_SYN_SIGNforNMifnotinputnoneEditField.Value;
            flag=strcmp(indexsyn,'none');
            if flag==0
                index=str2num(indexsyn);
                index1=index(1);
                index2=index(2);
                chushu1=floor(index1/16);
                yushu1=rem(index1,16);
                hang1=chushu1+3;
                lie1=40-yushu1;
                chushu2=floor(index2/16);
                yushu2=rem(index2,16);
                hang2=chushu2+3;
                lie2=40-yushu2;
                if hang1==hang2
                    config_data(hang1,lie2:lie1)=1;
                end
                
                if hang2-hang1==1
                    config_data(hang1,21:lie1)=1;
                    config_data(hang2,lie2:40)=1;
                end
                
                if hang2-hang1>1
                    config_data(hang1,21:lie1)=1;
                    config_data(hang2,lie2:40)=1;
                    for i=hang1+1:hang2-1
                        config_data(i,21:40)=1;
                    end
                end
            end


            %ok
            config_data(20,40)=app.SPI_AER_SRC_CTRL_nNEUREditField.Value;
            config_data(21,40)=app.SPI_OUT_AER_MONITOR_ENEditField.Value;
            config_data(24,40)=app.SPI_UPDATE_UNMAPPED_SYNEditField.Value;
            config_data(25,40)=app.SPI_PROPAGATE_UNMAPPED_SYNEditField.Value;
            config_data(26,40)=app.SPI_SDSP_ON_SYN_STIMEditField.Value;
            
            %for neuron and synapse monitor
            Neuron=app.SPI_MONITOR_NEUR_ADDRforNEditField.Value;
            Synapse=app.SPI_MONITOR_SYN_ADDRforNEditField.Value;
            N_index=bitget(Neuron,8:-1:1);
            S_index=bitget(Synapse,8:-1:1);
            config_data(22,33:40)=N_index;
            config_data(23,33:40)=S_index;
            
            path=app.forSPIEditField.Value;
            app.writetxt(config_data,path);
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            neuron_state_default=[0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,0,0,0,0,0,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,0,0,1,0,0,0,1,0,0,0,1,0,0,0,0,1,1,0,0,0,0,0,0,0,1,0,0,0,0,0,1,0,1];
            neuron_mem=zeros(256,128);
            for i=1:256
                neuron_mem(i,:)=neuron_state_default;
            end
            
            neuron_state=[0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,0,0,0,0,0,0,1,0,1,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1,0,0,1,0,0,0,1,0,0,0,1,0,0,0,0,1,1,0,0,0,0,0,0,0,1,0,0,0,0,0,1,0,1];
            neuron_state=fliplr(neuron_state);
            
            lif_izh_sel=app.LIF_sel1forLIFneuronEditField.Value;
            neuron_state(1)=lif_izh_sel;
            
            te_leak_str=app.leak_str0127theamountofleakageEditField.Value;
            leak_str=bitget(te_leak_str,7:-1:1);
            neuron_state(2:8)=fliplr(leak_str);
            
            leak_str=app.leak_en1forEnableEditField.Value;
            neuron_state(9)=leak_str;
            
            te_thr=app.thr0255thresholdEditField.Value;
            thr=bitget(te_thr,8:-1:1);
            neuron_state(10:17)=fliplr(thr);
            
            ca_en=app.ca_en1forEnableEditField.Value;
            neuron_state(18)=ca_en;
            
            te_thetamem=app.thetamem0255theSDSPthresholdEditField.Value;
            thetamem=bitget(te_thetamem,8:-1:1);
            neuron_state(19:26)=fliplr(thetamem);
            
            te_ca_theta1=app.ca_theta107EditField.Value;
            ca_theta1=bitget(te_ca_theta1,3:-1:1);
            neuron_state(27:29)=fliplr(ca_theta1);
            
            te_ca_theta2=app.ca_theta207EditField.Value;
            ca_theta2=bitget(te_ca_theta2,3:-1:1);
            neuron_state(30:32)=fliplr(ca_theta2);
            
            te_ca_theta3=app.ca_theta307EditField.Value;
            ca_theta3=bitget(te_ca_theta3,3:-1:1);
            neuron_state(33:35)=fliplr(ca_theta3);
            
            te_ca_leak=app.ca_leak031CalciumvariableleakagetimeconstantEditField.Value;
            ca_leak=bitget(te_ca_leak,5:-1:1);
            neuron_state(36:40)=fliplr(ca_leak);
            
            te_core=app.core0255membranepotentialEditField.Value;
            core=bitget(te_core,8:-1:1);
            neuron_state(71:78)=fliplr(core);
            
            te_calcium=app.calcium07CalciumvariableEditField.Value;
            calcium=bitget(te_calcium,3:-1:1);
            neuron_state(79:81)=fliplr(calcium);
            
            te_caleak_cnt=app.caleak_cnt031CalciumvariableleakagecounterEditField.Value;
            caleak_cnt=bitget(te_caleak_cnt,5:-1:1);
            neuron_state(82:86)=fliplr(caleak_cnt);
            
            neur_disable=app.neur_disable1forDisablestheneuronEditField.Value;
            neuron_state(128)=neur_disable;
            neuron_state=fliplr(neuron_state);
            
            index1=app.fEditField.Value;
            index2=app.specifytheneuroninrange0255EditField.Value;
            for j=index1+1:1:index2+1
                neuron_mem(j,:)=neuron_state;
            end
            
            neuron_mem=neuron_mem';
            
            path=app.forcoeEditField.Value;
            f=fullfile(path,'neuron.coe');
            fid = fopen (f,'w');
            fprintf( fid, 'memory_initialization_radix=2;\n');
            fprintf( fid, 'memory_initialization_vector =\n');
            for t=1:256*128
                fprintf(fid,'%d',neuron_mem(t));%一个一个将矩阵里面的数值打印进去
                if(rem(t,128)==0&&t~=256*128)
                    fprintf(fid,',\n');%打印逗号
                elseif(t==256*128)
                    fprintf(fid,';');%最后一行的分号
                end
            end
            fclose(fid);
            
        end

        % Button pushed function: OutputButton_6
        function OutputButton_6Pushed(app, event)
            matrix=[0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            
            word_ad=app.word_addrEditField_3.Value;
            word=bitget(word_ad,13:-1:1);
            byte_ad=app.byte_addrEditField_3.Value;
            byte=bitget(byte_ad,2:-1:1);
            mask=str2num(app.MaskEditField_2.Value);
            data=str2num(app.dataEditField_2.Value);
            matrix(6:7)=byte;
            matrix(8:20)=word;
            matrix(25:32)=mask;
            matrix(33:40)=data;
            outdata=matrix;
            path=app.forSPIEditField.Value;
            app.writetxt(outdata,path);
        end

        % Button pushed function: OutputButton_5
        function OutputButton_5Pushed(app, event)
            matrix=[1 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            
            word_ad=app.word_addrEditField_4.Value;
            word=bitget(word_ad,8:-1:1);
            byte_ad=app.byte_addrEditField_4.Value;
            byte=bitget(byte_ad,4:-1:1);
            mask=str2num(app.MaskEditField.Value);
            data=str2num(app.dataEditField.Value);
            matrix(9:12)=byte;
            matrix(13:20)=word;
            matrix(25:32)=mask;
            matrix(33:40)=data;
            outdata=matrix;
            path=app.forSPIEditField.Value;
            app.writetxt(outdata,path);
        end

        % Button pushed function: OutputButton_3
        function OutputButton_3Pushed(app, event)
            matrix=[1 0 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
                
            word_ad=app.word_addrEditField.Value;
            word=bitget(word_ad,8:-1:1);
            byte_ad=app.byte_addrEditField.Value;
            byte=bitget(byte_ad,4:-1:1);
            matrix(9:12)=byte;
            matrix(13:20)=word;
            outdata=matrix;
            path=app.forSPIEditField.Value;
            app.writetxt(outdata,path);
        end

        % Button pushed function: OutputButton_4
        function OutputButton_4Pushed(app, event)
            matrix=[0 1 0 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
                
            word_ad=app.word_addrEditField_2.Value;
            word=bitget(word_ad,13:-1:1);
            byte_ad=app.byte_addrEditField_2.Value;
            byte=bitget(byte_ad,2:-1:1);
            matrix(6:7)=byte;
            matrix(8:20)=word;
            outdata=matrix;
            path=app.forSPIEditField.Value;
            app.writetxt(outdata,path);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            preindex=str2num(app.Typethepresynapticneuronnumber0255EditField_2.Value);
            postindex=str2num(app.Typethepostsynapticneuronnumber0255EditField_2.Value);     
            mappingbit=app.setthemappingtablebit01EditField.Value;
            va_weight=app.setthesynapticweight07EditField.Value;
            weight=bitget(va_weight,3:-1:1);
            
            if length(preindex)==1 && length(postindex)==1
                postindex_b=bitget(postindex,8:-1:1);
                hangindex=1+(preindex*32+postindex_b(1)*16+postindex_b(2)*8+postindex_b(3)*4+postindex_b(4)*2+postindex_b(5));
                lieindex=32-((postindex_b(6)*4+postindex_b(7)*2+postindex_b(8))*4)-3;
                app.synapse(hangindex,lieindex:lieindex+3)=[mappingbit,weight];
            elseif length(preindex)>1 && length(postindex)==1
                    preindex1=preindex(1);
                    preindex2=preindex(2);
                    postindex_b=bitget(postindex,8:-1:1);
                    lieindex=32-((postindex_b(6)*4+postindex_b(7)*2+postindex_b(8))*4)-3;
                    for i=preindex1:preindex2
                        hangindex=1+(i*32+postindex_b(1)*16+postindex_b(2)*8+postindex_b(3)*4+postindex_b(4)*2+postindex_b(5));
                        app.synapse(hangindex,lieindex:lieindex+3)=[mappingbit,weight];
                    end
            elseif length(preindex)==1 && length(postindex)>1
                postindex1=postindex(1);
                postindex2=postindex(2);
                for j=postindex1:postindex2
                    postindex_b=bitget(j,8:-1:1);
                    hangindex=1+(preindex*32+postindex_b(1)*16+postindex_b(2)*8+postindex_b(3)*4+postindex_b(4)*2+postindex_b(5));
                    lieindex=32-((postindex_b(6)*4+postindex_b(7)*2+postindex_b(8))*4)-3;
                    app.synapse(hangindex,lieindex:lieindex+3)=[mappingbit,weight];             
                end
            elseif length(preindex)>1 && length(postindex)>1
                preindex1=preindex(1);
                preindex2=preindex(2);
                postindex1=postindex(1);
                postindex2=postindex(2);
                for i=preindex1:preindex2
                    for j=postindex1:postindex2
                        postindex_b=bitget(j,8:-1:1);
                        hangindex=1+(i*32+postindex_b(1)*16+postindex_b(2)*8+postindex_b(3)*4+postindex_b(4)*2+postindex_b(5));
                        lieindex=32-((postindex_b(6)*4+postindex_b(7)*2+postindex_b(8))*4)-3;
                        app.synapse(hangindex,lieindex:lieindex+3)=[mappingbit,weight];
                    end
                end
            end
            
                       
          %  app.writetxt(app.synapse);
        end

        % Button pushed function: OutputButton_7
        function OutputButton_7Pushed(app, event)
            z=app.synapse;
            z=z';
            x=8192;
            y=32;
            
            path=app.forcoeEditField.Value;
            f=fullfile(path,'synapse.coe');
            fid = fopen (f,'w');
            
            fprintf( fid, 'memory_initialization_radix=2;\n');
            fprintf( fid, 'memory_initialization_vector =\n');
            
            for i=1:x*y
                fprintf(fid,'%d',z(i));
                if(rem(i,y)==0&&i~=x*y)
                    fprintf(fid,',\n');
                elseif(i==x*y)
                    fprintf(fid,';');
                end
            end
            fclose(fid);

        end

        % Menu selected function: SPIMenu
        function SPIMenuSelected(app, event)
            imshow('SPI.jpg');
        end

        % Menu selected function: NeuronMenu
        function NeuronMenuSelected(app, event)
            imshow('neuron.jpg');
        end

        % Menu selected function: MEMMenu
        function MEMMenuSelected(app, event)
            imshow('mem.jpg');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.helpMenu = uimenu(app.UIFigure);
            app.helpMenu.Text = 'help';
            app.SPIMenu = uimenu(app.helpMenu);
            app.SPIMenu.MenuSelectedFcn = createCallbackFcn(app, @SPIMenuSelected, true);
            app.SPIMenu.Text = 'SPI';
            app.NeuronMenu = uimenu(app.helpMenu);
            app.NeuronMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuronMenuSelected, true);
            app.NeuronMenu.Text = 'Neuron';
            app.MEMMenu = uimenu(app.helpMenu);
            app.MEMMenu.MenuSelectedFcn = createCallbackFcn(app, @MEMMenuSelected, true);
            app.MEMMenu.Text = 'MEM';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.TextArea = uitextarea(app.MenuTab);
            app.TextArea.Value = {'1 - set ODIN configuration register'; ''; '2 - SPI readback and write '; ''; '3 - set neuron parameters by coe '; ''; '4 - set synapse parameters by coe '; ''};
            app.forSPIEditFieldLabel = uilabel(app.MenuTab);
            app.forSPIEditFieldLabel.Text = '输出地址 for SPI';
            app.forSPIEditField = uieditfield(app.MenuTab, 'text');
            app.forSPIEditField.Value = 'D:\workspace\MATLABproject\APP example\output';
            app.ODINconfiguratorLabel = uilabel(app.MenuTab);
            app.ODINconfiguratorLabel.Text = 'ODIN configurator';
            app.forcoeLabel = uilabel(app.MenuTab);
            app.forcoeLabel.Text = '输出地址 for coe';
            app.forcoeEditField = uieditfield(app.MenuTab, 'text');
            app.forcoeEditField.Value = 'D:\workspace\MATLABproject\APP example\output';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1';
            app.GridLayout = uigridlayout(app.Tab);
            app.SPI_GATE_ACTIVITYEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_GATE_ACTIVITYEditFieldLabel.Text = 'SPI_GATE_ACTIVITY';
            app.SPI_GATE_ACTIVITYEditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_GATE_ACTIVITYEditField.Limits = [0 1];
            app.SPI_OPEN_LOOPEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_OPEN_LOOPEditFieldLabel.Text = 'SPI_OPEN_LOOP	';
            app.SPI_OPEN_LOOPEditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_AER_SRC_CTRL_nNEUREditFieldLabel = uilabel(app.GridLayout);
            app.SPI_AER_SRC_CTRL_nNEUREditFieldLabel.Text = 'SPI_AER_SRC_CTRL_nNEUR';
            app.SPI_AER_SRC_CTRL_nNEUREditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_OUT_AER_MONITOR_ENEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_OUT_AER_MONITOR_ENEditFieldLabel.Text = 'SPI_OUT_AER_MONITOR_EN';
            app.SPI_OUT_AER_MONITOR_ENEditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_MONITOR_NEUR_ADDRforNEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_MONITOR_NEUR_ADDRforNEditFieldLabel.Text = 'SPI_MONITOR_NEUR_ADDR for N';
            app.SPI_MONITOR_NEUR_ADDRforNEditField = uieditfield(app.GridLayout, 'numeric');
            app.OutputButton_2 = uibutton(app.GridLayout, 'push');
            app.OutputButton_2.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_2Pushed, true);
            app.OutputButton_2.Text = 'Output';
            app.SPI_MONITOR_SYN_ADDRforNEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_MONITOR_SYN_ADDRforNEditFieldLabel.Text = 'SPI_MONITOR_SYN_ADDR	for N';
            app.SPI_MONITOR_SYN_ADDRforNEditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_UPDATE_UNMAPPED_SYNEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_UPDATE_UNMAPPED_SYNEditFieldLabel.Text = 'SPI_UPDATE_UNMAPPED_SYN';
            app.SPI_UPDATE_UNMAPPED_SYNEditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_PROPAGATE_UNMAPPED_SYNEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_PROPAGATE_UNMAPPED_SYNEditFieldLabel.Text = 'SPI_PROPAGATE_UNMAPPED_SYN';
            app.SPI_PROPAGATE_UNMAPPED_SYNEditField = uieditfield(app.GridLayout, 'numeric');
            app.SPI_SDSP_ON_SYN_STIMEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_SDSP_ON_SYN_STIMEditFieldLabel.Text = 'SPI_SDSP_ON_SYN_STIM';
            app.SPI_SDSP_ON_SYN_STIMEditField = uieditfield(app.GridLayout, 'numeric');
            app.NM0255Label = uilabel(app.GridLayout);
            app.NM0255Label.Text = 'N,M={ 0 ~ 255 }';
            app.SPI_SYN_SIGNforNMifnotinputnoneEditFieldLabel = uilabel(app.GridLayout);
            app.SPI_SYN_SIGNforNMifnotinputnoneEditFieldLabel.Text = 'SPI_SYN_SIGN  for N~M (if not, input ''none'')';
            app.SPI_SYN_SIGNforNMifnotinputnoneEditField = uieditfield(app.GridLayout, 'text');
            app.SPI_SYN_SIGNforNMifnotinputnoneEditField.Value = 'none';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2';
            app.READLabel = uilabel(app.Tab_2);
            app.READLabel.Text = 'READ';
            app.OutputButton_3 = uibutton(app.Tab_2, 'push');
            app.OutputButton_3.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_3Pushed, true);
            app.OutputButton_3.Text = 'Output';
            app.WRITELabel = uilabel(app.Tab_2);
            app.WRITELabel.Text = 'WRITE';
            app.word_addrEditFieldLabel = uilabel(app.Tab_2);
            app.word_addrEditFieldLabel.Text = 'word_addr';
            app.word_addrEditField = uieditfield(app.Tab_2, 'numeric');
            app.word_addrEditField.Limits = [0 255];
            app.byte_addrEditFieldLabel = uilabel(app.Tab_2);
            app.byte_addrEditFieldLabel.Text = 'byte_addr';
            app.byte_addrEditField = uieditfield(app.Tab_2, 'numeric');
            app.byte_addrEditField.Limits = [0 15];
            app.NeuronLabel = uilabel(app.Tab_2);
            app.NeuronLabel.Text = 'Neuron';
            app.SynapseLabel = uilabel(app.Tab_2);
            app.SynapseLabel.Text = 'Synapse';
            app.OutputButton_4 = uibutton(app.Tab_2, 'push');
            app.OutputButton_4.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_4Pushed, true);
            app.OutputButton_4.Text = 'Output';
            app.word_addrEditFieldLabel_2 = uilabel(app.Tab_2);
            app.word_addrEditFieldLabel_2.Text = 'word_addr';
            app.word_addrEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.word_addrEditField_2.Limits = [0 8191];
            app.byte_addrEditFieldLabel_2 = uilabel(app.Tab_2);
            app.byte_addrEditFieldLabel_2.Text = 'byte_addr';
            app.byte_addrEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.byte_addrEditField_2.Limits = [0 3];
            app.OutputButton_5 = uibutton(app.Tab_2, 'push');
            app.OutputButton_5.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_5Pushed, true);
            app.OutputButton_5.Text = 'Output';
            app.NeuronLabel_2 = uilabel(app.Tab_2);
            app.NeuronLabel_2.Text = 'Neuron';
            app.SynapseLabel_2 = uilabel(app.Tab_2);
            app.SynapseLabel_2.Text = 'Synapse';
            app.OutputButton_6 = uibutton(app.Tab_2, 'push');
            app.OutputButton_6.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_6Pushed, true);
            app.OutputButton_6.Text = 'Output';
            app.word_addrEditFieldLabel_3 = uilabel(app.Tab_2);
            app.word_addrEditFieldLabel_3.Text = 'word_addr';
            app.word_addrEditField_3 = uieditfield(app.Tab_2, 'numeric');
            app.word_addrEditField_3.Limits = [0 8191];
            app.byte_addrEditFieldLabel_3 = uilabel(app.Tab_2);
            app.byte_addrEditFieldLabel_3.Text = 'byte_addr';
            app.byte_addrEditField_3 = uieditfield(app.Tab_2, 'numeric');
            app.byte_addrEditField_3.Limits = [0 3];
            app.word_addrEditField_4Label = uilabel(app.Tab_2);
            app.word_addrEditField_4Label.Text = 'word_addr';
            app.word_addrEditField_4 = uieditfield(app.Tab_2, 'numeric');
            app.word_addrEditField_4.Limits = [0 255];
            app.byte_addrEditField_4Label = uilabel(app.Tab_2);
            app.byte_addrEditField_4Label.Text = 'byte_addr';
            app.byte_addrEditField_4 = uieditfield(app.Tab_2, 'numeric');
            app.byte_addrEditField_4.Limits = [0 8191];
            app.dataEditFieldLabel = uilabel(app.Tab_2);
            app.dataEditFieldLabel.Text = 'data';
            app.dataEditField = uieditfield(app.Tab_2, 'text');
            app.dataEditField.Value = '[ 0 0 0 0 0 0 0 0 ]';
            app.MaskEditFieldLabel = uilabel(app.Tab_2);
            app.MaskEditFieldLabel.Text = 'Mask';
            app.MaskEditField = uieditfield(app.Tab_2, 'text');
            app.MaskEditField.Value = '[ 0 0 0 0 0 0 0 0 ]';
            app.dataEditField_2Label = uilabel(app.Tab_2);
            app.dataEditField_2Label.Text = 'data';
            app.dataEditField_2 = uieditfield(app.Tab_2, 'text');
            app.dataEditField_2.Value = '[ 0 0 0 0 0 0 0 0 ]';
            app.MaskEditField_2Label = uilabel(app.Tab_2);
            app.MaskEditField_2Label.Text = 'Mask';
            app.MaskEditField_2 = uieditfield(app.Tab_2, 'text');
            app.MaskEditField_2.Value = '[ 0 0 0 0 0 0 0 0 ]';
            app.masked0nonmaskedLabel = uilabel(app.Tab_2);
            app.masked0nonmaskedLabel.Text = '(1=masked, 0=non-masked)';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3';
            app.GridLayout2 = uigridlayout(app.Tab_3);
            app.LIF_sel1forLIFneuronLabel = uilabel(app.GridLayout2);
            app.LIF_sel1forLIFneuronLabel.Text = 'LIF_sel (1 for LIF neuron)';
            app.LIF_sel1forLIFneuronEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LIF_sel1forLIFneuronEditField.Limits = [0 1];
            app.LIF_sel1forLIFneuronEditField.Value = 1;
            app.leak_str0127theamountofleakageEditFieldLabel = uilabel(app.GridLayout2);
            app.leak_str0127theamountofleakageEditFieldLabel.Text = '	leak_str (0~127 , the amount of leakage )';
            app.leak_str0127theamountofleakageEditField = uieditfield(app.GridLayout2, 'numeric');
            app.leak_str0127theamountofleakageEditField.Limits = [0 127];
            app.leak_str0127theamountofleakageEditField.Value = 2;
            app.leak_en1forEnableEditFieldLabel = uilabel(app.GridLayout2);
            app.leak_en1forEnableEditFieldLabel.Text = 'leak_en ( 1 for Enable )';
            app.leak_en1forEnableEditField = uieditfield(app.GridLayout2, 'numeric');
            app.leak_en1forEnableEditField.Limits = [0 1];
            app.leak_en1forEnableEditField.Value = 1;
            app.thr0255thresholdEditFieldLabel = uilabel(app.GridLayout2);
            app.thr0255thresholdEditFieldLabel.Text = 'thr (0~255 , threshold)';
            app.thr0255thresholdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.thr0255thresholdEditField.Limits = [0 255];
            app.thr0255thresholdEditField.Value = 128;
            app.ca_en1forEnableEditFieldLabel = uilabel(app.GridLayout2);
            app.ca_en1forEnableEditFieldLabel.Text = 'ca_en ( 1 for Enable )';
            app.ca_en1forEnableEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ca_en1forEnableEditField.Limits = [0 1];
            app.ca_en1forEnableEditField.Value = 1;
            app.thetamem0255theSDSPthresholdEditFieldLabel = uilabel(app.GridLayout2);
            app.thetamem0255theSDSPthresholdEditFieldLabel.Text = 'thetamem (0~255, the SDSP threshold)';
            app.thetamem0255theSDSPthresholdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.thetamem0255theSDSPthresholdEditField.Limits = [0 255];
            app.thetamem0255theSDSPthresholdEditField.Value = 32;
            app.ca_theta107EditFieldLabel = uilabel(app.GridLayout2);
            app.ca_theta107EditFieldLabel.Text = '	ca_theta1 (0~7)';
            app.ca_theta107EditField = uieditfield(app.GridLayout2, 'numeric');
            app.ca_theta107EditField.Limits = [0 7];
            app.ca_theta107EditField.Value = 1;
            app.ca_theta207EditFieldLabel = uilabel(app.GridLayout2);
            app.ca_theta207EditFieldLabel.Text = '	ca_theta2 (0~7)';
            app.ca_theta207EditField = uieditfield(app.GridLayout2, 'numeric');
            app.ca_theta207EditField.Limits = [0 7];
            app.ca_theta207EditField.Value = 2;
            app.ca_theta307EditFieldLabel = uilabel(app.GridLayout2);
            app.ca_theta307EditFieldLabel.Text = '	ca_theta3(0~7)';
            app.ca_theta307EditField = uieditfield(app.GridLayout2, 'numeric');
            app.ca_theta307EditField.Limits = [0 7];
            app.ca_theta307EditField.Value = 5;
            app.ca_leak031CalciumvariableleakagetimeconstantEditFieldLabel = uilabel(app.GridLayout2);
            app.ca_leak031CalciumvariableleakagetimeconstantEditFieldLabel.Text = 'ca_leak(0~31, Calcium variable leakage time constant)';
            app.ca_leak031CalciumvariableleakagetimeconstantEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ca_leak031CalciumvariableleakagetimeconstantEditField.Limits = [0 32];
            app.ca_leak031CalciumvariableleakagetimeconstantEditField.Value = 1;
            app.caleak_cnt031CalciumvariableleakagecounterEditFieldLabel = uilabel(app.GridLayout2);
            app.caleak_cnt031CalciumvariableleakagecounterEditFieldLabel.Text = 'caleak_cnt (0~31, Calcium variable leakage counter)';
            app.caleak_cnt031CalciumvariableleakagecounterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.caleak_cnt031CalciumvariableleakagecounterEditField.Limits = [0 31];
            app.caleak_cnt031CalciumvariableleakagecounterEditField.Value = 1;
            app.neur_disable1forDisablestheneuronEditFieldLabel = uilabel(app.GridLayout2);
            app.neur_disable1forDisablestheneuronEditFieldLabel.Text = 'neur_disable (1 for Disables the neuron)';
            app.neur_disable1forDisablestheneuronEditField = uieditfield(app.GridLayout2, 'numeric');
            app.neur_disable1forDisablestheneuronEditField.Limits = [0 1];
            app.core0255membranepotentialEditFieldLabel = uilabel(app.GridLayout2);
            app.core0255membranepotentialEditFieldLabel.Text = 'core ( 0~255 , membrane potential)';
            app.core0255membranepotentialEditField = uieditfield(app.GridLayout2, 'numeric');
            app.core0255membranepotentialEditField.Limits = [0 255];
            app.core0255membranepotentialEditField.Value = 10;
            app.calcium07CalciumvariableEditFieldLabel = uilabel(app.GridLayout2);
            app.calcium07CalciumvariableEditFieldLabel.Text = 'calcium ( 0~7 , Calcium variable)';
            app.calcium07CalciumvariableEditField = uieditfield(app.GridLayout2, 'numeric');
            app.calcium07CalciumvariableEditField.Limits = [0 7];
            app.calcium07CalciumvariableEditField.Value = 4;
            app.OutputButton = uibutton(app.GridLayout2, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.fEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fEditField.Limits = [0 255];
            app.specifytheneuroninrange0255EditFieldLabel = uilabel(app.GridLayout2);
            app.specifytheneuroninrange0255EditFieldLabel.Text = 'specify the neuron in range {0 ~ 255}';
            app.specifytheneuroninrange0255EditField = uieditfield(app.GridLayout2, 'numeric');
            app.specifytheneuroninrange0255EditField.Limits = [0 255];
            app.specifytheneuroninrange0255EditField.Value = 255;
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4';
            app.setthemappingtablebit01EditFieldLabel = uilabel(app.Tab_4);
            app.setthemappingtablebit01EditFieldLabel.Text = 'set the mapping table bit [ 0,1]';
            app.setthemappingtablebit01EditField = uieditfield(app.Tab_4, 'numeric');
            app.setthemappingtablebit01EditField.Limits = [0 1];
            app.setthemappingtablebit01EditField.Value = 1;
            app.setthesynapticweight07EditFieldLabel = uilabel(app.Tab_4);
            app.setthesynapticweight07EditFieldLabel.Text = 'set the synaptic weight [0, 7]';
            app.setthesynapticweight07EditField = uieditfield(app.Tab_4, 'numeric');
            app.setthesynapticweight07EditField.Limits = [0 7];
            app.setthesynapticweight07EditField.Value = 2;
            app.SetButton = uibutton(app.Tab_4, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.OutputButton_7 = uibutton(app.Tab_4, 'push');
            app.OutputButton_7.ButtonPushedFcn = createCallbackFcn(app, @OutputButton_7Pushed, true);
            app.OutputButton_7.Text = 'Output';
            app.Typethepresynapticneuronnumber0255EditField_2Label = uilabel(app.Tab_4);
            app.Typethepresynapticneuronnumber0255EditField_2Label.Text = 'Type the presynaptic neuron number  [0 , 255]';
            app.Typethepresynapticneuronnumber0255EditField_2 = uieditfield(app.Tab_4, 'text');
            app.Typethepresynapticneuronnumber0255EditField_2.Value = '[0 , 255]';
            app.Typethepostsynapticneuronnumber0255EditField_2Label = uilabel(app.Tab_4);
            app.Typethepostsynapticneuronnumber0255EditField_2Label.Text = 'Type the postsynaptic neuron number  [0 , 255]';
            app.Typethepostsynapticneuronnumber0255EditField_2 = uieditfield(app.Tab_4, 'text');
            app.Typethepostsynapticneuronnumber0255EditField_2.Value = '[0 , 255]';
            app.defaultmappingtablebit1enablesynapticweight2Label = uilabel(app.Tab_4);
            app.defaultmappingtablebit1enablesynapticweight2Label.Text = {'default : mapping table bit  1 (enable)'; 'synaptic weight 2'; ''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ODIN_config

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end